classdef Editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Toolbar              matlab.ui.container.Toolbar
        PushTool             matlab.ui.container.toolbar.PushTool
        GridLayout           matlab.ui.container.GridLayout
        ModeSwitchLabel      matlab.ui.control.Label
        ModeSwitch           matlab.ui.control.Switch
        ResolveMeshButton    matlab.ui.control.Button
        AlphaEditFieldLabel  matlab.ui.control.Label
        AlphaEditField       matlab.ui.control.NumericEditField
        ImportButton         matlab.ui.control.Button
        MorphSlider          matlab.ui.control.Slider
        MorphSliderLabel     matlab.ui.control.Label
        AddButton_2          matlab.ui.control.Button
        LayerSpinner         matlab.ui.control.Spinner
        LayerSpinnerLabel    matlab.ui.control.Label
        ShapesListBox        matlab.ui.control.ListBox
        SetShapeButton       matlab.ui.control.Button
        ExportButton         matlab.ui.control.Button
        AddButton            matlab.ui.control.Button
        ObjectsListBox       matlab.ui.control.ListBox
        Area_ROI             matlab.ui.control.UIAxes
        Area_Img             matlab.ui.control.UIAxes
        Area_Back            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        objs % Description
        current % Description
        morph % Description
        mode % Description
        current_ROI % Description
    end
    
    methods (Access = private)
        
        function dispMeshes(app)
            if app.current ~= 0
                image(app.Area_Img,app.objs(app.current).img,"AlphaData",app.objs(app.current).mask);
                points = app.objs(app.current).mesh_points;
                meshes = app.objs(app.current).meshes;                
                %rearrangeROI(app,points);
                plotEdges(app,points,meshes);
            end
        end
        
        function resolveMeshes(app)
            if app.current ~= 0
                ROI = app.current_ROI;
                points_num = length(ROI);
                points = zeros(points_num,2);
                for i = 1:points_num
                    points(i,1) = ROI{i}.Position(1);
                    points(i,2) = ROI{i}.Position(2);
                end
                app.objs(app.current).mesh_points = points;
                app.objs(app.current).shape_points = points;
                meshes = point2mesh(points,app.AlphaEditField.Value);
                app.objs(app.current).meshes = meshes;

            end
        end
        
        function setShape(app)
            if app.morph ~= 1
                rearrangeROI(app,app.objs(app.current).shape_points);
            end
            ROI = app.current_ROI;
            points_num = length(ROI);
            points = zeros(points_num,2);
            for i = 1:points_num
                points(i,1) = ROI{i}.Position(1);
                points(i,2) = ROI{i}.Position(2);
            end
            app.objs(app.current).shape_points = points;
            
            %if size(app.objs(app.current).shape_points)~=size(app.objs(app.current).mesh_points)
            %    app.objs(app.current).mesh_points = points;
            %end
        end
        
        function plotEdges(app,points,meshes)
            [mesh_num,~] = size(meshes);
            hold(app.Area_Img,"on");
            for i = 1:mesh_num
                idxs = meshes(i,:);
                plot(app.Area_Img,[points(idxs,1); points(idxs(1),1)],[points(idxs,2); points(idxs(1),2)],color=[0 1 0])
            end
            hold(app.Area_Img,"off");
        end
        
        function dispShape(app)
            [img,mask,morph_points] = obj2img(app.objs(app.current),app.morph); 
            image(app.Area_Img,img,"AlphaData",mask);
            meshes = app.objs(app.current).meshes;  
            plotEdges(app,morph_points,meshes);
            if app.morph ~= 1
                rearrangeROI(app,morph_points,true);
            end
        end
        
        function rearrangeROI(app,points,istemp)
            if ~exist('istemp','var'), istemp = false; end
            
            cla(app.Area_ROI);
            [points_num, ~] = size(points);
            
            if ~istemp
                app.current_ROI = {};
            end
            
            for i = 1:points_num
                if istemp
                    drawpoint(app.Area_ROI,Position=[points(i,1) points(i,2)],color=[0 1 0]);
                else
                    app.current_ROI{i} = drawpoint(app.Area_ROI,Position=[points(i,1) points(i,2)],color=[0 1 0]);
                    addlistener(app.current_ROI{i},'MovingROI',@(src,event) ROIcallback(app,event));
                end
            end
            
            ROIcallback(app);
        end
        
        function setupArea(app)
            [W,H,~]=size(app.objs(length(app.objs)).img);
            
            app.Area_Img.XLim = [-inf,inf];
            app.Area_Img.YLim = [-inf,inf];
            
            app.Area_Back.XLim = [-inf,inf];
            app.Area_Back.YLim = [-inf,inf];
            
            app.Area_ROI.YDir = 'reverse';    
            app.Area_ROI.XLim = [1,W];
            app.Area_ROI.YLim = [1,H];
            
            image(app.Area_Back,imread("imgs\bk.png"));
            
            cla(app.Area_ROI);
        end
        
        function ROIcallback(app, ~)
            if app.ModeSwitch.Value == "Mesh"
                resolveMeshes(app);
                dispMeshes(app);
            else
                setShape(app);
                dispShape(app)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.current = 0;
            app.morph = 1;
            app.ModeSwitch.Value = "Mesh";
            pbaspect(app.Area_Img,[1 1 1]);
            pbaspect(app.Area_ROI,[1 1 1]);
            pbaspect(app.Area_Back,[1 1 1]);
            %app.ObjectsListBox.Items(1) = {"aaa"};
        end

        % Button pushed function: AddButton
        function AddObjButtonPushed(app, event)
            [file,path] = uigetfile("*.png");
            if file ~= 0
                app.current = length(app.objs)+1;
                [img,~,mask]=imread(append(path,file));
                app.objs(app.current).name = file;
                app.objs(app.current).img = img;
                app.objs(app.current).mask = mask;
                app.objs(app.current).mesh_points = [];
                app.objs(app.current).meshes = [];
                app.objs(app.current).shape_points = [];
                %item = string(file);
                setupArea(app);
                image(app.Area_Img,app.objs(app.current).img,"AlphaData",app.objs(app.current).mask);
            end
            dispMeshes(app);
        end

        % Button pushed function: ResolveMeshButton
        function ResolveMeshesButtonPushed(app, event)
            if app.current~=0
                if app.ModeSwitch.Value == "Shape"
                    points = app.objs(app.current).mesh_points;
                    rearrangeROI(app,points); 
                end
            
                resolveMeshes(app);
                dispMeshes(app);
            
                if app.ModeSwitch.Value == "Shape"    
                    app.ModeSwitch.Value = "Mesh"; 
                end
            end        
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.AlphaEditField.Value;
        end

        % Button down function: Area_ROI
        function Area_ROIButtonDown(app, event)
            if app.current ~= 0 && app.ModeSwitch.Value == "Mesh"
                app.current_ROI{1+length(app.current_ROI)} = drawpoint(app.Area_ROI,color=[0 1 0]);
                resolveMeshes(app);
                rearrangeROI(app,app.objs(app.current).mesh_points);
                
            end    
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [file,path] = uiputfile('*.mat');
            if file ~= 0
                OBJS = app.objs;
                save(append(path,file),"OBJS",'-mat');
            end
        end

        % Button pushed function: SetShapeButton
        function SetShapeButtonPushed(app, event)
            if app.current ~= 0
                app.ModeSwitch.Value = "Shape";
                setShape(app);
                dispShape(app);
            end
        end

        % Value changing function: MorphSlider
        function MorphSliderValueChanging(app, event)
            if app.current~=0
                app.morph = event.Value;
                app.ModeSwitch.Value="Shape";
                dispShape(app);
            end
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            if app.current~=0
                app.MorphSlider.Value = 1;
                if app.ModeSwitch.Value == "Shape"
                    resolveMeshes(app);
                    if ~isempty(app.objs(app.current).shape_points)
                        points = app.objs(app.current).shape_points;
                        rearrangeROI(app,points);
                    end
                    setShape(app);
                    dispShape(app);
                else
                    if ~isempty(app.objs(app.current).mesh_points)
                        points = app.objs(app.current).mesh_points;
                        rearrangeROI(app,points);
                    end
                    dispMeshes(app);
                end
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
              [file,path] = uigetfile();
              if file ~= 0
                  load(append(path,file),"-mat","OBJS");
                  app.objs = OBJS;
                  app.current = length(app.objs);
                  app.ModeSwitch.Value = "Mesh";
                  
                  setupArea(app);
                  rearrangeROI(app,app.objs(app.current).mesh_points);
                  dispMeshes(app);
              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Area_Back = uiaxes(app.GridLayout);
            xlabel(app.Area_Back, 'X')
            ylabel(app.Area_Back, 'Y')
            zlabel(app.Area_Back, 'Z')
            app.Area_Img = uiaxes(app.GridLayout);
            xlabel(app.Area_Img, 'X')
            ylabel(app.Area_Img, 'Y')
            zlabel(app.Area_Img, 'Z')
            app.Area_ROI = uiaxes(app.GridLayout);
            xlabel(app.Area_ROI, 'X')
            ylabel(app.Area_ROI, 'Y')
            zlabel(app.Area_ROI, 'Z')
            app.Area_ROI.ButtonDownFcn = createCallbackFcn(app, @Area_ROIButtonDown, true);
            app.ObjectsListBox = uilistbox(app.GridLayout);
            app.ObjectsListBox.Items = {'項目 1', '項目 2'};
            app.ObjectsListBox.Value = '項目 1';
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddObjButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SetShapeButton = uibutton(app.GridLayout, 'push');
            app.SetShapeButton.ButtonPushedFcn = createCallbackFcn(app, @SetShapeButtonPushed, true);
            app.SetShapeButton.Text = 'Set Shape';
            app.ShapesListBox = uilistbox(app.GridLayout);
            app.ShapesListBox.Items = {};
            app.ShapesListBox.Value = {};
            app.LayerSpinnerLabel = uilabel(app.GridLayout);
            app.LayerSpinnerLabel.Text = 'Layer';
            app.LayerSpinner = uispinner(app.GridLayout);
            app.AddButton_2 = uibutton(app.GridLayout, 'push');
            app.AddButton_2.Text = 'Add';
            app.MorphSliderLabel = uilabel(app.GridLayout);
            app.MorphSliderLabel.Text = 'Morph';
            app.MorphSlider = uislider(app.GridLayout);
            app.MorphSlider.Limits = [0 1];
            app.MorphSlider.ValueChangingFcn = createCallbackFcn(app, @MorphSliderValueChanging, true);
            app.MorphSlider.Value = 1;
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.AlphaEditField = uieditfield(app.GridLayout, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = Inf;
            app.AlphaEditFieldLabel = uilabel(app.GridLayout);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.ResolveMeshButton = uibutton(app.GridLayout, 'push');
            app.ResolveMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ResolveMeshesButtonPushed, true);
            app.ResolveMeshButton.Text = 'Resolve Mesh';
            app.ModeSwitch = uiswitch(app.GridLayout, 'slider');
            app.ModeSwitch.Items = {'Mesh', 'Shape'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Mesh';
            app.ModeSwitchLabel = uilabel(app.GridLayout);
            app.ModeSwitchLabel.Text = 'Mode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Editor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end